classdef homework1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        flipButton     matlab.ui.control.Button
        computeButton  matlab.ui.control.Button
        loadButton     matlab.ui.control.Button
        UIAxes_4       matlab.ui.control.UIAxes
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            %オリジナル画像
            filename="C:\MATLAB_pimd\logo_csse.png"
            Origin_img=imread(filename)
            imshow(Origin_img,'Parent',app.UIAxes)
        end

        % Button pushed function: computeButton
        function computeButtonPushed(app, event)
            %画像を２値化
            filename="C:\MATLAB_pimd\logo_csse.png"
            Origin_img=imread(filename)
            Gray_img=rgb2gray(Origin_img)
            imshow(Gray_img,'Parent',app.UIAxes_2)
        end

        % Button pushed function: flipButton
        function flipButtonPushed(app, event)
            %画像の左右を反転
            filename="C:\MATLAB_pimd\logo_csse.png"
            Origin_img=imread(filename)
            Flip_img=flip(Origin_img)
            imshow(Flip_img,'Parent',app.UIAxes_3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Image')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.computeButton = uibutton(app.UIFigure, 'push');
            app.computeButton.ButtonPushedFcn = createCallbackFcn(app, @computeButtonPushed, true);
            app.computeButton.Text = 'compute';
            app.flipButton = uibutton(app.UIFigure, 'push');
            app.flipButton.ButtonPushedFcn = createCallbackFcn(app, @flipButtonPushed, true);
            app.flipButton.Text = 'flip';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = homework1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end